classdef ex3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Exerccio3Label         matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        FunorepresentadaLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x = 0:0.01:2*pi;
            y = sin(x);
            
            plot(app.UIAxes,x,y,'k-')
            title(app.UIAxes,'y = sin(x)');
            app.UIAxes.XLim = [0,2*pi];
            app.UIAxes.YLim = [-1,1];
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            x = 0:0.01:2*pi;

            if value == "sin(x)"
                y = sin(x);
            else
                y = cos(x);
            end
            
            plot(app.UIAxes,x,y,'k-')

            title(app.UIAxes,strcat('y = ',value))
            app.UIAxes.XLim = [0,2*pi];
            app.UIAxes.YLim = [-1,1];
            ylabel(app.UIAxes, value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'sin(x)')
            zlabel(app.UIAxes, 'Z')
            app.FunorepresentadaLabel = uilabel(app.UIFigure);
            app.FunorepresentadaLabel.Text = 'Função representada:';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'sin(x)', 'cos(x)'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'sin(x)';
            app.Exerccio3Label = uilabel(app.UIFigure);
            app.Exerccio3Label.Text = 'Exercício 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end